classdef Regression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        OrderEditFieldLabel           matlab.ui.control.Label
        OrderEditField                matlab.ui.control.EditField
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.EditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.EditField
        Button                        matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RegressionLabel               matlab.ui.control.Label
        CoEfficientsEditFieldLabel    matlab.ui.control.Label
        CoEfficientsEditField         matlab.ui.control.EditField
        CoEfficientStartsfroma0Label  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            order=app.OrderEditField.Value
            datax=app.XEditField.Value
            datay=app.YEditField.Value
            or=str2num(order)
            x=str2num(datax)
            y=str2num(datay)
            n=size(x);
for i=1:or+1
    p=i-1;
    for j=1:or+1
        if i==1 & j==1
            A(1,1)=n(2)
        else
            A(i,j)=sum(x.^p)
        end
    p=p+1;
    end
     B(i,1)=sum(y.*(x.^(i-1)));
end
C=inv(A)*B

sum1=0;
for i=1:length(x)
    for j=1:length(C)
        sum1=sum1+C(j)*x(i)^(j-1);
    end
    Y1(i)=sum1;
end
plot(app.UIAxes,x,Y1,'-ko')
m=size(C)
text1= sprintf('%.4f, ',C);
app.CoEfficientsEditField.Value=text1

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OrderEditFieldLabel = uilabel(app.LeftPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.LeftPanel, 'text');
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'text');
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LeftPanel, 'text');
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Regression Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RegressionLabel = uilabel(app.RightPanel);
            app.RegressionLabel.Text = 'Regression';
            app.CoEfficientsEditFieldLabel = uilabel(app.RightPanel);
            app.CoEfficientsEditFieldLabel.Text = 'CoEfficients';
            app.CoEfficientsEditField = uieditfield(app.RightPanel, 'text');
            app.CoEfficientStartsfroma0Label = uilabel(app.RightPanel);
            app.CoEfficientStartsfroma0Label.Text = 'Co-Efficient Starts from a0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Regression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end